classdef Pedal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        reverbPedal           matlab.ui.control.Image
        reverbLampa           matlab.ui.control.Lamp
        reverbKnapp           matlab.ui.control.Button
        reverbLabel           matlab.ui.control.Label
        reverbWetnessLabel    matlab.ui.control.Label
        reverbMixLabel        matlab.ui.control.Label
        reverbDecayKnob       matlab.ui.control.Knob
        reverbWetnessKnob     matlab.ui.control.Knob
        reverbMixKnob         matlab.ui.control.Knob
        reverbDecayLabel      matlab.ui.control.Label
        distortionPedal       matlab.ui.control.Image
        delayPedal            matlab.ui.control.Image
        distortionLabel       matlab.ui.control.Label
        distortionButton      matlab.ui.control.Button
        delayButton           matlab.ui.control.Button
        distortionDesign      matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        distortionKnob        matlab.ui.control.Knob
        distortionLamp        matlab.ui.control.Lamp
        distortionDriveLabel  matlab.ui.control.Label
        delayLabel            matlab.ui.control.Label
        delayDesign           matlab.ui.control.Label
        speakerLabel          matlab.ui.control.Label
        delayMixKnob          matlab.ui.control.Knob
        speakerDesignBrown    matlab.ui.control.Label
        delayLamp             matlab.ui.control.Lamp
        speakerVolumeKnob     matlab.ui.control.Knob
        speakerVolumeLabel    matlab.ui.control.Label
        speakerOnOffLabel     matlab.ui.control.Label
        delayTimeKnob         matlab.ui.control.Knob
        delayDecayKnob        matlab.ui.control.Knob
        delayTimeLabel        matlab.ui.control.Label
        delayMixLabel         matlab.ui.control.Label
        DecayLabel_2          matlab.ui.control.Label
        speakerSwitch         matlab.ui.control.RockerSwitch
        speakerLamp           matlab.ui.control.Lamp
    end

    
    properties (Access = public)
    WetDryMix = 0
    DecayFactor = 1
    Diffusion = 0.5
    fileReader;
    deviceWriter;
    reverb;
    kDist=0.9;
    reverbSwitch = 0;
    distortionSwitch = 0;
    delaySwitch = 0;
    speakerBreaker = 0;
    %distValue = event.Value;
    % app.kDist = 2*distValue/(1-distValue);
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.fileReader = dsp.AudioFileReader('gitarr.wav','SamplesPerFrame',1024);
        app.deviceWriter = audioDeviceWriter('SampleRate', app.fileReader.SampleRate);
        app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        
        end

        % Value changed function: reverbDecayKnob
        function reverbDecayKnobValueChanged(app, event)
            decayValue = event.Value;
            app.DecayFactor = decayValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        end

        % Value changed function: reverbMixKnob
        function reverbMixKnobValueChanged(app, event)
            mixValue = event.Value;
            app.WetDryMix = mixValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        end

        % Button pushed function: reverbKnapp
        function reverbKnappButtonPushed(app, event)
            if app.reverbSwitch == 0
                app.reverbLampa.Color = [0.89,0.12,0.12];
                app.reverbSwitch = 1;
                %pedal på
            else
                app.reverbLampa.Color = [0.37,0.05,0.05];
                app.reverbSwitch = 0;
            end
        end

        % Value changed function: reverbWetnessKnob
        function reverbWetnessKnobValueChanged(app, event)
            wetValue = event.Value;
            app.WetDryMix = wetValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);            
        end

        % Button pushed function: distortionButton
        function distortionButtonPushed(app, event)
            if app.distortionSwitch == 0
                app.distortionLamp.Color = [0.89,0.12,0.12];
                app.distortionSwitch = 1;
                %pedal på
            else
                app.distortionLamp.Color = [0.37,0.05,0.05];
                app.distortionSwitch = 0;
            end
        end

        % Button pushed function: delayButton
        function delayButtonPushed(app, event)
            if app.delaySwitch == 0
                app.delayLamp.Color = [0.89,0.12,0.12];
                app.delaySwitch = 1;
            else
                app.delayLamp.Color = [0.37,0.05,0.05];
                app.delaySwitch = 0;
            end
        end

        % Value changed function: speakerSwitch
        function speakerSwitchValueChanged(app, event)
            if app.speakerBreaker == 0
                app.speakerLamp.Color = [0.89,0.12,0.12];
                app.speakerBreaker = 1;
                %volym på            
            else 
                app.speakerLamp.Color = [0.37,0.05,0.05]; 
                app.speakerBreaker = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.reverbPedal = uiimage(app.UIFigure);
            app.reverbPedal.ImageSource = 'reverb.png';
            app.reverbLampa = uilamp(app.UIFigure);
            app.reverbKnapp = uibutton(app.UIFigure, 'push');
            app.reverbKnapp.ButtonPushedFcn = createCallbackFcn(app, @reverbKnappButtonPushed, true);
            app.reverbKnapp.Text = '';
            app.reverbLabel = uilabel(app.UIFigure);
            app.reverbLabel.Text = 'REVERB';
            app.reverbWetnessLabel = uilabel(app.UIFigure);
            app.reverbWetnessLabel.Text = {'WETNESS'; ''};
            app.reverbMixLabel = uilabel(app.UIFigure);
            app.reverbMixLabel.Text = {'MIX'; ''};
            app.reverbDecayKnob = uiknob(app.UIFigure, 'continuous');
            app.reverbDecayKnob.Limits = [0 1];
            app.reverbDecayKnob.MajorTicks = [0 24 36 42 54 60 78 84 90 100];
            app.reverbDecayKnob.ValueChangedFcn = createCallbackFcn(app, @reverbDecayKnobValueChanged, true);
            app.reverbDecayKnob.MinorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60 63 66 69 72 75 78 81 84 87 90 93 96 100];
            app.reverbWetnessKnob = uiknob(app.UIFigure, 'continuous');
            app.reverbWetnessKnob.Limits = [0 1];
            app.reverbWetnessKnob.ValueChangedFcn = createCallbackFcn(app, @reverbWetnessKnobValueChanged, true);
            app.reverbMixKnob = uiknob(app.UIFigure, 'continuous');
            app.reverbMixKnob.Limits = [0 1];
            app.reverbMixKnob.ValueChangedFcn = createCallbackFcn(app, @reverbMixKnobValueChanged, true);
            app.reverbDecayLabel = uilabel(app.UIFigure);
            app.reverbDecayLabel.Text = {'Decay'; ''};
            app.distortionPedal = uiimage(app.UIFigure);
            app.distortionPedal.ImageSource = 'dist.png';
            app.delayPedal = uiimage(app.UIFigure);
            app.delayPedal.ImageSource = 'delay.png';
            app.distortionLabel = uilabel(app.UIFigure);
            app.distortionLabel.Text = 'DISTORTION';
            app.distortionButton = uibutton(app.UIFigure, 'push');
            app.distortionButton.ButtonPushedFcn = createCallbackFcn(app, @distortionButtonPushed, true);
            app.distortionButton.Text = '';
            app.delayButton = uibutton(app.UIFigure, 'push');
            app.delayButton.ButtonPushedFcn = createCallbackFcn(app, @delayButtonPushed, true);
            app.delayButton.Text = '';
            app.distortionDesign = uilabel(app.UIFigure);
            app.distortionDesign.Text = '';
            app.distortionLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.distortionKnob = uiknob(app.UIFigure, 'continuous');
            app.distortionKnob.MajorTicks = [0 30 70 100];
            app.distortionKnob.MajorTickLabels = {'0', '30', '70', '100'};
            app.distortionDriveLabel = uilabel(app.UIFigure);
            app.distortionDriveLabel.Text = {'Drive'; ''};
            app.delayLabel = uilabel(app.UIFigure);
            app.delayLabel.Text = 'DELAY';
            app.delayDesign = uilabel(app.UIFigure);
            app.delayDesign.Text = '';
            app.delayLamp = uilamp(app.UIFigure);
            app.delayMixKnob = uiknob(app.UIFigure, 'continuous');
            app.speakerDesignBrown = uilabel(app.UIFigure);
            app.speakerDesignBrown.Text = '';
            app.speakerLabel = uilabel(app.UIFigure);
            app.speakerLabel.Text = {'Mysigt'; ''};
            app.speakerVolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.speakerLamp = uilamp(app.UIFigure);
            app.speakerSwitch = uiswitch(app.UIFigure, 'rocker');
            app.speakerSwitch.ValueChangedFcn = createCallbackFcn(app, @speakerSwitchValueChanged, true);
            app.speakerVolumeLabel = uilabel(app.UIFigure);
            app.speakerVolumeLabel.Text = 'VOLUME';
            app.speakerOnOffLabel = uilabel(app.UIFigure);
            app.speakerOnOffLabel.Text = {'On/Off'; ''};
            app.delayTimeKnob = uiknob(app.UIFigure, 'continuous');
            app.delayDecayKnob = uiknob(app.UIFigure, 'continuous');
            app.delayTimeLabel = uilabel(app.UIFigure);
            app.delayTimeLabel.Text = 'Time';
            app.delayMixLabel = uilabel(app.UIFigure);
            app.delayMixLabel.Text = 'Mix';
            app.DecayLabel_2 = uilabel(app.UIFigure);
            app.DecayLabel_2.Text = 'Decay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pedal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end